classdef select_gui_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ResetButton          matlab.ui.control.Button
        VideoButton          matlab.ui.control.Button
        ScanButton           matlab.ui.control.Button
        SelectButton         matlab.ui.control.Button
        WasherDropDown       matlab.ui.control.DropDown
        WasherDropDownLabel  matlab.ui.control.Label
        BackgroundButton     matlab.ui.control.Button
        Display              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        testMode = false % Test on saved image
        vid % Webcam video
        gameState % Information object
        testBg = 'test_boards\game_board_test_set_Page_01.png'
        testCurr = 'test_boards\game_board_test_set_Page_02.png'
        motorModel = 'MotorModel_Sp23_V22b'
        motorLoc = [320, 240] % Either need to calculate this or hard code

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            clc;
            fprintf("App started\n")
            title(app.Display, 'App Startup')
            axis(app.Display, 'image', 'off');

            if app.testMode
                % Clear display
                axis(app.Display, 'image', 'off');
            else
                imaqreset;
                webcam = 2;
                app.vid = videoinput('winvideo', webcam, 'MJPG_640x480');
                app.vid.ReturnedColorSpace = 'rgb';
                app.vid.FramesPerTrigger = 1;
                triggerconfig(app.vid, 'manual'); % running, but not logging frames to memory
                vidRes = app.vid.VideoResolution;
                hImage = image(app.Display, zeros(vidRes(2), vidRes(1), 3, 'uint8'));
                axis(app.Display, 'image', 'off');
                start(app.vid)
                preview(app.vid, hImage);
                fprintf("Video started\n")
            end
            
            % Load motor simulink model
            % fprintf("Loading simulink model\n")
            % % open_system(app.motorModel);
            % load_system(app.motorModel);
            % set_param(app.motorModel, 'StopTime', 'inf');
            % set_param(app.motorModel, 'EnablePacing', 'on', 'PacingRate', 1);
            % thetaBlk = [app.motorModel '/desiredPosition'];
            % set_param(thetaBlk, 'Value', '0'); % Initializes desired position angle to 0
            % set_param(app.motorModel,'SimulationCommand','start');

            % Tell background image must be manually taken
            title(app.Display, 'Please save a background');
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            k = app.WasherDropDown.Value;
            
            % Checks that items were found
            if ~isfield(app.gameState, 'stickerNum')
                uialert(app.UIFigure, 'Must scan first', 'Select Failed');
                return
            end
            
            % Checks that there are items in the dropdown menu
            if isempty(k)
                uialert(app.UIFigure, 'Must select washer first', 'Select Failed');
                return
            end
            
            % Prints position
            pos = app.gameState.stickerPos(k, :);
            fprintf("Selected %d: %s %s at (%.0f, %.0f)\n", k, ...
                app.gameState.stickerColor(k), app.gameState.stickerShape(k), pos(1), pos(2));
            
            % Sets desired position of dc motor
            thetaBlk = [app.motorModel '/desiredPosition'];
            dx = pos(1) - app.motorLoc(1);
            dy = app.motorLoc(2) - pos(2); % Image y points down
            thetaVal = atan2d(dy, dx); % Calculates the angle motor needs to point to 
            set_param(thetaBlk, 'Value', num2str(thetaVal));

        end

        % Button pushed function: BackgroundButton
        function BackgroundButtonPushed(app, event)
            gameState = app.gameState;

            if app.testMode
                % Gets test background image
                gameState.bgImg = imread(app.testBg);
                title(app.Display, 'Background Image');
                imshow(gameState.bgImg, 'Parent', app.Display);
                axis(app.Display, 'image', 'off');
                fprintf("Test background image taken\n")
            else
                % Gets current webcam frame
                gameState.bgImg = getsnapshot(app.vid);
                imshow(gameState.bgImg, 'Parent', app.Display);
                title(app.Display, 'Background Image');
                pause(1);
                vidRes = app.vid.VideoResolution;
                hImage = image(app.Display, zeros(vidRes(2), vidRes(1), 3, 'uint8'));
                axis(app.Display, 'image', 'off');
                preview(app.vid, hImage);
                title(app.Display, 'Current preview');
                fprintf("Background image taken\n")
            end
    
            figure()
            imshow(gameState.bgImg)
            title('Background Image')
            gameState.bgSize = size(gameState.bgImg);
            app.gameState = gameState;
            
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            gameState = app.gameState;
            if ~isfield(gameState, 'bgImg')
                uialert(app.UIFigure, 'Must get background first', 'Scan Failed');
                return
            end
    
            if app.testMode
                % Gets test current frame image
                gameState.currImg = imread(app.testCurr);
                title(app.Display, 'Current Image');
                imshow(gameState.currImg, 'Parent', app.Display);
                drawnow;
                fprintf('Test current image taken\n')
            else
                % Gets current webcam frame
                gameState.currImg = getsnapshot(app.vid);
                title(app.Display, 'Current Image');
                imshow(gameState.currImg, 'Parent', app.Display);
                fprintf('Current image taken\n')
            end

            figure()
            imshow(gameState.currImg)
            title('Current Image')

            %% Initial image processing & bw transformation
            colorThreshold = 30;
            gameState.diffImg = imabsdiff(gameState.bgImg, gameState.currImg);

            ringMask = max(gameState.diffImg, [], 3) > colorThreshold;

            ringMask = imopen(ringMask, strel('disk', 2));
            ringMask = imclose(ringMask, strel('disk', 5));
            ringMask = bwareaopen(ringMask, 300);

            gameState.diffImgBw = imfill(ringMask, 'holes');
            gameState.diffImgBw = bwareaopen(gameState.diffImgBw, 500);
            % gameState.diffImg = gameState.bgImg - gameState.currImg;
            % gameState.diffImgBw = gameState.diffImg;

            % Find updated pixels
            % for row = 1:gameState.bgSize(1)
            %     for col = 1:gameState.bgSize(2)
            %         gameState.diffImgBw(row, col) = any(gameState.diffImgBw(row, col, :) > colorThreshold);
            %     end
            % end
            % gameState.diffImgBw = any(gameState.diffImgBw, 3); % Turns to Bw

            % Erode and Dilate to get rid of noise
            % SE = strel('disk', 10);
            % SD = strel('disk', 10);
            % gameState.diffImgBw = imerode(gameState.diffImgBw, SE);
            % gameState.diffImgBw = imdilate(gameState.diffImgBw, SD);
            % gameState.diffImgBw = imfill(gameState.diffImgBw, 'holes'); 


            figure()
            imshowpair(ringMask, gameState.diffImgBw, 'montage')
            title('Ring mask  |  Filled mask');
            drawnow;

            %% Get position and basic info from processed difference
            gameState.diffProp = regionprops('table', gameState.diffImgBw, ...
                'Centroid', 'BoundingBox', 'Circularity', 'Extent', 'PixelIdxList');
            gameState.stickerPos = gameState.diffProp.Centroid;
            gameState.stickerNum = height(gameState.stickerPos);
            gameState.stickerBox = gameState.diffProp.BoundingBox;

            %% Get color from ring pixels (not the centroid, which is the hole)
            gameState.colorKey = dictionary(["Red","Orange","Yellow","Green","Cyan","Blue","Magenta"], ...
                [0.0, 0.08, 0.16, 0.33, 0.5, 0.66, 0.83]);
            colorNames = keys(gameState.colorKey);
            colorHues  = values(gameState.colorKey);
            gameState.stickerColor = strings(gameState.stickerNum, 1);

            hsvImg = rgb2hsv(gameState.currImg);
            H = hsvImg(:,:,1);  
            S = hsvImg(:,:,2);  
            V = hsvImg(:,:,3);

            for k = 1:gameState.stickerNum
                idx = gameState.diffProp.PixelIdxList{k};
                if iscell(idx), idx = idx{1}; end      % single-region table edge case
                idx = idx(ringMask(idx));              % keep ring pixels, drop the hole

                if median(V(idx)) < 0.25
                    gameState.stickerColor(k) = "Black";
                elseif median(S(idx)) < 0.25
                    gameState.stickerColor(k) = "White";
                else
                    sat = idx(S(idx) > 0.3);           % only well-saturated pixels
                    ang = 2*pi*H(sat);                 % circular mean (red wraps 0/1)
                    hMean = mod(atan2(mean(sin(ang)), mean(cos(ang))) / (2*pi), 1);
                    d = abs(hMean - colorHues);
                    d = min(d, 1 - d);
                    [~, nearest] = min(d);
                    gameState.stickerColor(k) = colorNames(nearest);
                end
            end

            % figure()
            % imshow(gameState.diffImgBw)
            % % imshow(gameState.diffImgBw, 'Parent', app.Display);
            % title('Detected Motion');
            % drawnow;
            % 
            % 
            % %% Get position and basic info from processed difference
            % gameState.diffProp = regionprops('table', gameState.diffImgBw, 'Centroid', 'BoundingBox', 'Circularity', 'Extent'); % Gets necessary properties
            % gameState.stickerPos = gameState.diffProp.Centroid;
            % gameState.stickerNum = height(gameState.stickerPos);
            % gameState.stickerBox = gameState.diffProp.BoundingBox;
            % 
            % %% Get color from curr image
            % gameState.colorKey = dictionary(["Red", "Orange", "Yellow", "Green", "Cyan", "Blue", "Magenta"], [0.0, 0.08, 0.16, 0.33, 0.5, 0.66, 0.83]);
            % gameState.stickerColor = strings(gameState.stickerNum, 1);
            % rgbVal = zeros(gameState.stickerNum, 3);
            % 
            % % get rgb value from centroid location
            % for row = 1:gameState.stickerNum
            %     X = round(gameState.stickerPos(row, 1));
            %     Y = round(gameState.stickerPos(row, 2));
            %     rgbVal(row, :) = double(reshape(gameState.currImg(Y, X, :), 1, 3));
            % end
            % 
            % % switch rgb to hsv for better color recognition
            % hsv = rgb2hsv(rgbVal / 255);
            % hue = hsv(:, 1);
            % colorNames = keys(gameState.colorKey);
            % colorHues = values(gameState.colorKey);
            % 
            % % finds nearest color
            % for row = 1:gameState.stickerNum
            %     d = abs(hue(row) - colorHues);
            %     d = min(d, 1 - d);
            %     [~, nearestColor] = min(d);
            %     gameState.stickerColor(row) = colorNames(nearestColor);
            % end

            %% Get shape from curr image
            gameState.stickerShape = strings(gameState.stickerNum, 1);
            circularity = gameState.diffProp.Circularity;
            extent = gameState.diffProp.Extent;

            for obj = 1:gameState.stickerNum
                if (circularity(obj) > 0.85) && ((0.6 < extent(obj)) && (extent(obj) < 0.9))
                    gameState.stickerShape(obj) = "Circle";
                elseif (circularity(obj) < 0.9) && (extent(obj) > 0.9)
                    gameState.stickerShape(obj) = "Square";
                elseif (circularity(obj) < 0.7) && (extent(obj) < 0.55)
                    gameState.stickerShape(obj) = "Triangle";       
                else
                    gameState.stickerShape(obj) = "Unknown";
                end
            end
            
            fprintf('Current frame processed\n')
            %% Print processed information to the app's axes
            ax = app.Display;
            % Remove the previous scan's drawings (keeps the image/live video underneath)
            delete(findobj(ax, 'Type', 'line', '-or', 'Type', 'rectangle', '-or', 'Type', 'text'));
            imshow(gameState.currImg, 'Parent', ax);
            hold(ax, 'on')

            plot(ax, gameState.stickerPos(:,1), gameState.stickerPos(:,2), 'k.', 'MarkerSize', 20) % Draw position centriod dots

            for k = 1:gameState.stickerNum
                rectangle('Parent', ax, 'Position', gameState.stickerBox(k,:), 'EdgeColor', 'k', 'LineWidth', 2) % Draw Bounding box
            end

            % Number objects
            labelX1 = gameState.stickerPos(:,1) - 40;
            labelY1 = gameState.stickerBox(:,2) - 15;

            for k = 1:gameState.stickerNum
                text(ax, labelX1(k), labelY1(k), string(k), ...
                    'Color', 'k', ...
                    'FontWeight', 'bold', ...
                    'FontSize', 10)
            end

            % Draw object color
            labelX2 = gameState.stickerPos(:,1); % Centered horizontally on the sticker
            labelY2 = gameState.stickerBox(:,2) - 15;
            text(ax, labelX2, labelY2, gameState.stickerColor, ...
                'Color', 'k', ...
                'FontWeight', 'bold', ...
                'FontSize', 10)

            % Draw object shape
            labelX3 = gameState.stickerPos(:,1) - 25;
            labelY3 = gameState.stickerBox(:,2) - 35;
            text(ax, labelX3, labelY3, gameState.stickerShape, ...
                'Color', 'k', ...
                'FontWeight', 'bold', ...
                'FontSize', 10)

            hold(ax, 'off')
            title(ax, 'Processed Current Image')
            drawnow

            %% Fill the WasherDropDown 
            if gameState.stickerNum == 0
                app.WasherDropDown.Items = {'None found'};
                app.WasherDropDown.ItemsData = 0;
            else
                labels = string(1:gameState.stickerNum)' + ": " + gameState.stickerColor + " " + gameState.stickerShape;
                app.WasherDropDown.Items = cellstr(labels);
                app.WasherDropDown.ItemsData = 1:gameState.stickerNum;
            end

            app.gameState = gameState;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.vid)
                closepreview(app.vid);
                stop(app.vid)
                delete(app.vid);
            end

            try
                set_param(app.motorModel, 'SimulationCommand', 'stop');
            catch
                fprintf("Simulink not closed")
            end
            delete(app)
            
        end

        % Button pushed function: VideoButton
        function VideoButtonPushed(app, event)
            vidRes = app.vid.VideoResolution;
            hImage = image(app.Display, zeros(vidRes(2), vidRes(1), 3, 'uint8'));
            axis(app.Display, 'image', 'off');
            preview(app.vid, hImage);
            title(app.Display, 'Current preview');
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            axis(app.Display, 'image', 'off');
            cla(app.Display)
            app.WasherDropDown.Items = {};
            app.WasherDropDown.ItemsData = {};
            clear app.gameState app.vid;
            clc;
            startupFcn(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Display = uiaxes(app.UIFigure);
            title(app.Display, 'Title')
            xlabel(app.Display, 'X')
            ylabel(app.Display, 'Y')
            zlabel(app.Display, 'Z')
            app.BackgroundButton = uibutton(app.UIFigure, 'push');
            app.BackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @BackgroundButtonPushed, true);
            app.BackgroundButton.Text = 'Background';
            app.WasherDropDownLabel = uilabel(app.UIFigure);
            app.WasherDropDownLabel.Text = 'Washer';
            app.WasherDropDown = uidropdown(app.UIFigure);
            app.WasherDropDown.Items = {};
            app.WasherDropDown.Placeholder = 'Scan for options';
            app.WasherDropDown.Value = {};
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ScanButton = uibutton(app.UIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.VideoButton = uibutton(app.UIFigure, 'push');
            app.VideoButton.ButtonPushedFcn = createCallbackFcn(app, @VideoButtonPushed, true);
            app.VideoButton.Text = 'Video';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = select_gui_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end